classdef CarDetection < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        carDetection       matlab.ui.Figure
        Panel              matlab.ui.container.Panel
        GridLayout         matlab.ui.container.GridLayout
        GridLayout3        matlab.ui.container.GridLayout
        CarDetectionLabel  matlab.ui.control.Label
        Image5             matlab.ui.control.Image
        GridLayout4        matlab.ui.container.GridLayout
        videoPanel         matlab.ui.control.UIAxes
        imagePanel         matlab.ui.control.UIAxes
        GridLayout5        matlab.ui.container.GridLayout
        openVideo          matlab.ui.control.Image
        stopImage          matlab.ui.control.Image
        Image7             matlab.ui.control.Image
    end

    
    properties (Access = private)
        executing
        stop
    end

    
    methods (Access = public)
        
       function r = getStop(app)
            r = app.stop;
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            executing = false;
            stop = false;
            drawnow;
            app.carDetection.WindowState = 'maximized';
        end

        % Image clicked function: openVideo
        function openVideoImageClicked(app, event)
            if app.executing
                app.stop = true;
                pause(1);
                app.executing = false;
            end
            [videoName, directory]=uigetfile({'*.*','All files'});
            app.stop = false;
            app.executing = true;
            CarDetectionFunction(directory, videoName, app.videoPanel, app.imagePanel, @app.getStop);
        end

        % Image clicked function: Image7
        function Image7Clicked(app, event)
            if app.executing
                app.stop = true;
                pause(1);
                app.executing = false;
            end
            close(app.carDetection);
            SmartCities;
        end

        % Image clicked function: stopImage
        function stopImageClicked(app, event)
            if app.executing
                app.stop = true;
                app.executing = false;
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.carDetection = uifigure('Visible', 'off');
            app.carDetection.Name = 'Smart Cities';
            app.Panel = uipanel(app.carDetection);
            app.GridLayout = uigridlayout(app.Panel);
            app.GridLayout3 = uigridlayout(app.GridLayout);
            app.CarDetectionLabel = uilabel(app.GridLayout3);
            app.CarDetectionLabel.Text = 'Car Detection';
            app.Image5 = uiimage(app.GridLayout3);
            app.Image5.ImageSource = 'car.png';
            app.GridLayout4 = uigridlayout(app.GridLayout);
            app.videoPanel = uiaxes(app.GridLayout4);
            title(app.videoPanel, '')
            xlabel(app.videoPanel, '')
            ylabel(app.videoPanel, '')
            app.videoPanel.XTick = [];
            app.videoPanel.YTick = [];
            app.imagePanel = uiaxes(app.GridLayout4);
            title(app.imagePanel, '')
            xlabel(app.imagePanel, '')
            ylabel(app.imagePanel, '')
            app.imagePanel.XTick = [];
            app.imagePanel.XTickLabel = '';
            app.imagePanel.YTick = [];
            app.imagePanel.YTickLabel = '';
            app.GridLayout5 = uigridlayout(app.GridLayout);
            app.openVideo = uiimage(app.GridLayout5);
            app.openVideo.ImageClickedFcn = createCallbackFcn(app, @openVideoImageClicked, true);
            app.openVideo.ImageSource = 'play-button.png';
            app.stopImage = uiimage(app.GridLayout5);
            app.stopImage.ImageClickedFcn = createCallbackFcn(app, @stopImageClicked, true);
            app.stopImage.ImageSource = 'stop-houston-astros-logo-vector-png-transparent-houston-astros-16.png';
            app.Image7 = uiimage(app.GridLayout5);
            app.Image7.ImageClickedFcn = createCallbackFcn(app, @Image7Clicked, true);
            app.Image7.ImageSource = 'reply.png';
            % Show the figure after all components are created
            app.carDetection.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = CarDetection

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.carDetection)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.carDetection)
        end
    end
end