classdef Retraining < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        queries                         matlab.ui.Figure
        Panel                           matlab.ui.container.Panel
        GridLayout                      matlab.ui.container.GridLayout
        GridLayout3                     matlab.ui.container.GridLayout
        RetrainingLabel                 matlab.ui.control.Label
        Image5                          matlab.ui.control.Image
        GridLayout11                    matlab.ui.container.GridLayout
        backImage                       matlab.ui.control.Image
        retrainImage                    matlab.ui.control.Image
        GridLayout12                    matlab.ui.container.GridLayout
        WeightLearnRateFactorEditField  matlab.ui.control.NumericEditField
        BiasLearnRateFactorLabel        matlab.ui.control.Label
        BiasLearnRateFactorEditField    matlab.ui.control.NumericEditField
        WeightLearnRateFactorLabel      matlab.ui.control.Label
        MiniBatchSizeEditField          matlab.ui.control.NumericEditField
        SolverMethodDropDown            matlab.ui.control.DropDown
        MaxEpochsEditField              matlab.ui.control.NumericEditField
        InitialLearnRateEditField       matlab.ui.control.NumericEditField
        ValidationFrequencyEditField    matlab.ui.control.NumericEditField
        ValidationPatienceEditField     matlab.ui.control.NumericEditField
        SolverMethodLabel               matlab.ui.control.Label
        MiniBatchSizeLabel              matlab.ui.control.Label
        MaxEpochsLabel                  matlab.ui.control.Label
        InitialLearnRateLabel           matlab.ui.control.Label
        ValidationFrequencyLabel        matlab.ui.control.Label
        ValidationPatienceLabel         matlab.ui.control.Label
        LastFullyConnectedLayerLabel    matlab.ui.control.Label
        TrainingOptionsLabel            matlab.ui.control.Label
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            drawnow;
            app.queries.WindowState = 'maximized';
            
        end

        % Image clicked function: backImage
        function backImageClicked(app, event)
            close(app.queries);
            Learning;
        end

        % Image clicked function: retrainImage
        function retrainImageClicked(app, event)
           configData.WeightLearnRateFactor = app.WeightLearnRateFactorEditField.Value;
           configData.BiasLearnRateFactor = app.BiasLearnRateFactorEditField.Value;
           configData.SolverMethod = app.SolverMethodDropDown.Value;
           configData.MiniBatchSize = app.MiniBatchSizeEditField.Value;
           configData.MaxEpochs = app.MaxEpochsEditField.Value;
           configData.InitialLearnRate = app.InitialLearnRateEditField.Value;
           configData.ValidationFrequency = app.ValidationFrequencyEditField.Value;
           configData.ValidationPatience = app.ValidationPatienceEditField.Value;
           LearningFunction(configData);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.queries = uifigure('Visible', 'off');
            app.queries.Name = 'Smart Cities';
            app.Panel = uipanel(app.queries);
            app.GridLayout = uigridlayout(app.Panel);
            app.GridLayout3 = uigridlayout(app.GridLayout);
            app.RetrainingLabel = uilabel(app.GridLayout3);
            app.RetrainingLabel.Text = ' Retraining';
            app.Image5 = uiimage(app.GridLayout3);
            app.Image5.ImageSource = '171-1712673_tuercas-png-logo-de-tuercas-png.png';
            app.GridLayout11 = uigridlayout(app.GridLayout);
            app.backImage = uiimage(app.GridLayout11);
            app.backImage.ImageClickedFcn = createCallbackFcn(app, @backImageClicked, true);
            app.backImage.ImageSource = 'reply.png';
            app.retrainImage = uiimage(app.GridLayout11);
            app.retrainImage.ImageClickedFcn = createCallbackFcn(app, @retrainImageClicked, true);
            app.retrainImage.ImageSource = 'learning.png';
            app.GridLayout12 = uigridlayout(app.GridLayout);
            app.WeightLearnRateFactorEditField = uieditfield(app.GridLayout12, 'numeric');
            app.WeightLearnRateFactorEditField.LowerLimitInclusive = 'off';
            app.WeightLearnRateFactorEditField.Limits = [0 Inf];
            app.WeightLearnRateFactorEditField.Value = 20;
            app.BiasLearnRateFactorLabel = uilabel(app.GridLayout12);
            app.BiasLearnRateFactorLabel.Text = 'Bias Learn RateFactor:';
            app.BiasLearnRateFactorEditField = uieditfield(app.GridLayout12, 'numeric');
            app.BiasLearnRateFactorEditField.LowerLimitInclusive = 'off';
            app.BiasLearnRateFactorEditField.Limits = [0 Inf];
            app.BiasLearnRateFactorEditField.Value = 20;
            app.WeightLearnRateFactorLabel = uilabel(app.GridLayout12);
            app.WeightLearnRateFactorLabel.Text = 'Weight Learn Rate Factor:';
            app.MiniBatchSizeEditField = uieditfield(app.GridLayout12, 'numeric');
            app.MiniBatchSizeEditField.LowerLimitInclusive = 'off';
            app.MiniBatchSizeEditField.Limits = [0 Inf];
            app.MiniBatchSizeEditField.Value = 10;
            app.SolverMethodDropDown = uidropdown(app.GridLayout12);
            app.SolverMethodDropDown.Items = {'sgdm', 'rmsprop', 'adam', ''};
            app.SolverMethodDropDown.Value = 'sgdm';
            app.MaxEpochsEditField = uieditfield(app.GridLayout12, 'numeric');
            app.MaxEpochsEditField.LowerLimitInclusive = 'off';
            app.MaxEpochsEditField.Limits = [0 Inf];
            app.MaxEpochsEditField.Value = 60;
            app.InitialLearnRateEditField = uieditfield(app.GridLayout12, 'numeric');
            app.InitialLearnRateEditField.LowerLimitInclusive = 'off';
            app.InitialLearnRateEditField.Limits = [0 Inf];
            app.InitialLearnRateEditField.Value = 0.0001;
            app.ValidationFrequencyEditField = uieditfield(app.GridLayout12, 'numeric');
            app.ValidationFrequencyEditField.LowerLimitInclusive = 'off';
            app.ValidationFrequencyEditField.Limits = [0 Inf];
            app.ValidationFrequencyEditField.Value = 3;
            app.ValidationPatienceEditField = uieditfield(app.GridLayout12, 'numeric');
            app.ValidationPatienceEditField.LowerLimitInclusive = 'off';
            app.ValidationPatienceEditField.Limits = [0 Inf];
            app.ValidationPatienceEditField.Value = Inf;
            app.SolverMethodLabel = uilabel(app.GridLayout12);
            app.SolverMethodLabel.Text = 'Solver Method:';
            app.MiniBatchSizeLabel = uilabel(app.GridLayout12);
            app.MiniBatchSizeLabel.Text = 'Mini Batch Size:';
            app.MaxEpochsLabel = uilabel(app.GridLayout12);
            app.MaxEpochsLabel.Text = 'Max Epochs:';
            app.InitialLearnRateLabel = uilabel(app.GridLayout12);
            app.InitialLearnRateLabel.Text = 'Initial Learn Rate:';
            app.ValidationFrequencyLabel = uilabel(app.GridLayout12);
            app.ValidationFrequencyLabel.Text = 'Validation Frequency:';
            app.ValidationPatienceLabel = uilabel(app.GridLayout12);
            app.ValidationPatienceLabel.Text = 'Validation Patience:';
            app.LastFullyConnectedLayerLabel = uilabel(app.GridLayout12);
            app.LastFullyConnectedLayerLabel.Text = 'Last Fully Connected Layer';
            app.TrainingOptionsLabel = uilabel(app.GridLayout12);
            app.TrainingOptionsLabel.Text = 'Training Options';
            % Show the figure after all components are created
            app.queries.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Retraining

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.queries)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.queries)
        end
    end
end